classdef SLM_setup_app_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LampPiano                      matlab.ui.control.Lamp
        TabGroup2                      matlab.ui.container.TabGroup
        Write2SLMTab                   matlab.ui.container.Tab
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        SLM_Write_3                    matlab.ui.control.Button
        SLM_Show                       matlab.ui.control.Button
        SLM_Write                      matlab.ui.control.Button
        EditField_SLM_Write            matlab.ui.control.EditField
        LP0mTab                        matlab.ui.container.Tab
        OptimizerPanel                 matlab.ui.container.Panel
        GratingSize_LP_opt             matlab.ui.control.NumericEditField
        GratingSizeEditField_2Label    matlab.ui.control.Label
        PixelSize_LP_opt               matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel_2      matlab.ui.control.Label
        RunButton_LP_opt               matlab.ui.control.Button
        r0EditField                    matlab.ui.control.NumericEditField
        r0EditFieldLabel               matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        SLM_Write_2                    matlab.ui.control.Button
        ZernikePolTab                  matlab.ui.container.Tab
        EditField_ZerPol_vector        matlab.ui.control.EditField
        Label_ZerPol                   matlab.ui.control.Label
        EditField_Exclude_ZerPol       matlab.ui.control.NumericEditField
        EditField_Generate_ZerPol      matlab.ui.control.NumericEditField
        Write2SLM_ZerPol               matlab.ui.control.Button
        Show_ZerPol                    matlab.ui.control.Button
        Exclude_ZerPol                 matlab.ui.control.Button
        Generate_ZerPol                matlab.ui.control.Button
        GATab                          matlab.ui.container.Tab
        MakropixelgratingPanel         matlab.ui.container.Panel
        GratingSizeEditField           matlab.ui.control.NumericEditField
        GratingSizeEditFieldLabel      matlab.ui.control.Label
        PixelSizeEditField             matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel        matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        ZernikePolPanel                matlab.ui.container.Panel
        UBEditField                    matlab.ui.control.EditField
        UBEditFieldLabel               matlab.ui.control.Label
        LBEditField                    matlab.ui.control.EditField
        LBEditFieldLabel               matlab.ui.control.Label
        StartCam_2                     matlab.ui.control.Button
        Tab                            matlab.ui.container.Tab
        Lamp_SLM                       matlab.ui.control.Lamp
        LampOSA                        matlab.ui.control.Lamp
        LampPM100USB                   matlab.ui.control.Lamp
        LampELL14                      matlab.ui.control.Lamp
        LampCam                        matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        CameraTab                      matlab.ui.container.Tab
        LoadreferenceimagePanel        matlab.ui.container.Panel
        ImgCmpButton                   matlab.ui.control.Button
        StartCam_5                     matlab.ui.control.Button
        RefImg_Var                     matlab.ui.control.EditField
        VariableEditFieldLabel         matlab.ui.control.Label
        RefImg_Scal                    matlab.ui.control.NumericEditField
        ScalingEditFieldLabel          matlab.ui.control.Label
        RefImg_path                    matlab.ui.control.EditField
        FilepathEditField_4Label       matlab.ui.control.Label
        StartCam_4                     matlab.ui.control.Button
        CameraPanel                    matlab.ui.container.Panel
        saturationButton               matlab.ui.control.Button
        saturationField                matlab.ui.control.NumericEditField
        HistogramButton                matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        FWHMCamera                     matlab.ui.control.Button
        FilepathEditField              matlab.ui.control.EditField
        FilepathEditFieldLabel         matlab.ui.control.Label
        D4sCheckBox                    matlab.ui.control.CheckBox
        ZoomEditField                  matlab.ui.control.NumericEditField
        ZoomEditFieldLabel             matlab.ui.control.Label
        PreviewCamera_2                matlab.ui.control.Button
        PreviewCamera                  matlab.ui.control.Button
        AutoExposure                   matlab.ui.control.Button
        ExposureEditField              matlab.ui.control.NumericEditField
        ExposureEditFieldLabel         matlab.ui.control.Label
        DriverEditField                matlab.ui.control.EditField
        DriverEditFieldLabel           matlab.ui.control.Label
        ExitCam                        matlab.ui.control.Button
        StartCam                       matlab.ui.control.Button
        SLMTab                         matlab.ui.container.Tab
        ClosingSLMwillkillMatLabLabel  matlab.ui.control.Label
        SLM_LoadLUT                    matlab.ui.control.Button
        LUTEditField                   matlab.ui.control.EditField
        LUTEditFieldLabel              matlab.ui.control.Label
        Label_SLM                      matlab.ui.control.Label
        Driver_SLM                     matlab.ui.control.EditField
        DriverEditField_2Label         matlab.ui.control.Label
        Close_SLM                      matlab.ui.control.Button
        Open_SLM                       matlab.ui.control.Button
        StageTab                       matlab.ui.container.Tab
        ELL14Panel                     matlab.ui.container.Panel
        Go2PowerLabel                  matlab.ui.control.Label
        Go2DegreeLabel                 matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        ELL14Position                  matlab.ui.control.NumericEditField
        HomeButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        PortEditField                  matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        PowMetTab                      matlab.ui.container.Tab
        PM100USBPanel                  matlab.ui.container.Panel
        DATA_PM100USB                  matlab.ui.control.NumericEditField
        READPM100USB                   matlab.ui.control.Button
        SETWAVELabel                   matlab.ui.control.Label
        SetWAVPM100USB                 matlab.ui.control.NumericEditField
        ClosePM100USB                  matlab.ui.control.Button
        OpenPM100USB                   matlab.ui.control.Button
        OSATab                         matlab.ui.container.Tab
        OSAPanel                       matlab.ui.container.Panel
        FilepathOSA                    matlab.ui.control.EditField
        FilepathEditField_2Label       matlab.ui.control.Label
        PreviewCamera_3                matlab.ui.control.Button
        Get_Spec_OSA                   matlab.ui.control.Button
        Close_OSA                      matlab.ui.control.Button
        Open_OSA                       matlab.ui.control.Button
        PianoTab                       matlab.ui.container.Tab
        PortEditField_2                matlab.ui.control.EditField
        PortEditField_2Label           matlab.ui.control.Label
        CloseButton_2                  matlab.ui.control.Button
        OpenButton_2                   matlab.ui.control.Button
        GAoptimizationPanel            matlab.ui.container.Panel
        UB_Piano                       matlab.ui.control.EditField
        UBEditField_2Label             matlab.ui.control.Label
        LB_Piano                       matlab.ui.control.EditField
        LBEditField_2Label             matlab.ui.control.Label
        StartCam_3                     matlab.ui.control.Button
        Firstdigitservonumber24positionLabel  matlab.ui.control.Label
        EditFieldPianoCmd              matlab.ui.control.EditField
        SendcommandButton              matlab.ui.control.Button
        NumberofservosEditField        matlab.ui.control.NumericEditField
        NumberofservosEditFieldLabel   matlab.ui.control.Label
        MeasurementTab                 matlab.ui.container.Tab
        Range2scan                     matlab.ui.control.EditField
        RangetoscanEditFieldLabel      matlab.ui.control.Label
        StartmeasurementButton         matlab.ui.control.Button
        BasefilenameEditField          matlab.ui.control.EditField
        BasefilenameEditFieldLabel     matlab.ui.control.Label
        FilepathEditField_3            matlab.ui.control.EditField
        FilepathEditField_2Label_2     matlab.ui.control.Label
        SavedataPanel                  matlab.ui.container.Panel
        PolarizationCheckBox           matlab.ui.control.CheckBox
        PowerCheckBox                  matlab.ui.control.CheckBox
        SLMimageCheckBox               matlab.ui.control.CheckBox
        SpectrumCheckBox               matlab.ui.control.CheckBox
        ImageCheckBox                  matlab.ui.control.CheckBox
        DropDown_2                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        cam = []; % camera pointer
        cam_param = [];
        PrevFigH = [];
        ELL14 = [];
        PM100USB = [];
        OSA = [];
        OSA_wl = [];
        SLM = struct('width',[],'height',[], 'x', [], 'y', [], 'r', [],...
            'teta', [], 'img', [], 'number', []);
        ZerPol = struct('Dic',[],'Nind',[], 'Mind', []);
        Piano = [];
        RefImg = [];
        RefImgScaling = [];
        RefImgGrid = [];

    end
    
    methods (Access = private)
        
        function frame = get_cam_frame(app)
            if ~isempty(app.cam)
                app.cam.Acquisition.Freeze(uEye.Defines.DeviceParameter.Wait);
                [~, ~] = app.cam.Acquisition.IsFinished;

                [~, tmp] = app.cam.Memory.CopyToArray(app.cam_param.MemId, uEye.Defines.ColorMode.Mono12);
                frame =  squeeze( reshape(int32(tmp),...
                    [app.cam_param.Bits/12, app.cam_param.Width, app.cam_param.Height]) );
            end
        end
        
        function results = im2mfd(app, frame)
            im = double( frame );
%             thr = sum(im(1,:))/length(im(1,:));
            thr = 2;
            im = im - thr;
            im(im<0) = 0;
            [x, y] = meshgrid(-size(frame,2)/2:size(frame,2)/2-1,...
                -size(frame,1)/2:size(frame,1)/2-1);
            xCent = sum(sum(x.*im))/sum(sum(im));
            yCent = sum(sum(y.*im))/sum(sum(im));
            D4sigmaX = 4*sqrt( sum(sum((x-xCent).^2.*im))/sum(sum(im)) );
            D4sigmaY = 4*sqrt( sum(sum((y-yCent).^2.*im))/sum(sum(im)) );
            D4sigmaXY = sum(sum((x-xCent).*(y-yCent).*im))/sum(sum(im));

            results.xCent = xCent;
            results.yCent = yCent;
            results.D4sigmaX = D4sigmaX;
            results.D4sigmaY = D4sigmaY;
            results.D4sigmaXY = D4sigmaY;
            theta = 0.5*atan( D4sigmaXY/((0.25*D4sigmaX)^2 -(0.25*D4sigmaY)^2) );
            t = linspace(0, 2*pi, 40);
            xelip = D4sigmaX/2*cos(t);
            yelip = D4sigmaY/2*sin(t);
            R  = [cos(theta) -sin(theta); sin(theta)  cos(theta)];
            CorR = R*[xelip ; yelip];
            results.xel = double(app.cam_param.Width)/2+xCent+CorR(1,:);
            results.yel = double(app.cam_param.Height)/2+yCent+CorR(2,:);
        end
        
        
        function results = MakeGaussFit2image(app)
            frame = get_cam_frame(app);
            im = double( frame );
            im = imgaussfilt(im,2);
            im = im-1;
            im(im<0) = 0;
            [x, y] = meshgrid(1:size(im,2), 1:size(im,1));
            
            xCent = sum(sum(x.*im))/sum(sum(im));
            yCent = sum(sum(y.*im))/sum(sum(im));
            
            %% fix x
            xdata = im(:,round(xCent))/max(im(:,round(xCent)));
            xx = y(:,1);
            [xData, yData] = prepareCurveData( xx, xdata );
            % Set up fittype and options.
            ft = fittype( 'gauss1' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 -Inf 0];
            opts.StartPoint = [1 yCent 10];
            % Fit model to data.
            [fitresult_x, gof_x] = fit( xData, yData, ft, opts );
            
            %% fix y
            ydata = im(round(yCent),:)/max(im(round(yCent),:));
            yy = x(1,:);
            [xData, yData] = prepareCurveData( yy, ydata );
            % Set up fittype and options.
            ft = fittype( 'gauss1' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 -Inf 0];
            opts.StartPoint = [1 xCent 10];
            % Fit model to data.
            [fitresult_y, gof_y] = fit( xData, yData, ft, opts );
            %%
            results.x = fitresult_x.c1*2.35/sqrt(2);
            results.y = fitresult_y.c1*2.35/sqrt(2);
            %%
            
            figH = figure('pos', [100 100 1000 500]);
            subplot(1,2,1)
            plot(xx - fitresult_x.b1, xdata, 'ob')
            hold on
            plot(xx - fitresult_x.b1, feval(fitresult_x,xx), 'LineWidth', 2)
            hold off
            xlim([-4*fitresult_x.c1 4*fitresult_x.c1])
            ylim([0 1.1])
            grid on;
            title(['Horizontal FWHM: ' num2str(fitresult_x.c1*2.35/sqrt(2))])
            
            subplot(1,2,2)
            plot(yy - fitresult_y.b1, ydata, 'ob')
            hold on
            plot(yy - fitresult_y.b1, feval(fitresult_y,yy), 'LineWidth', 2)
            hold off
            xlim([-4*fitresult_y.c1 4*fitresult_y.c1])
            ylim([0 1.1])
            grid on;
            title(['Horizontal FWHM: ' num2str(fitresult_y.c1*2.35/sqrt(2))])
            waitfor(figH)
            
        end
        
        function results = deg2hex(app, teta)
            n = length(teta);
            ascii = dec2hex(round(teta));
            results = cell([1 n]);
            % zero pad
            for i = 1:n
               hex_ = ascii(i, :);
               while length(hex_) < 8
                   hex_ = ['0' hex_];
               end
               results{i} = hex_;
            end
        end
        
        function ExpValue = SetAutoExposure(app)
            [~, val] = app.cam.Timing.Exposure.GetRange;
            val = struct('Minimum', val.Minimum, 'Maximum', val.Maximum,...
                 'Increment', val.Increment);
            ExpVal = val.Minimum:val.Increment:val.Maximum;
            [~, TmpExp] = app.cam.Timing.Exposure.Get;
            flag = 1;
            while flag
                 frame = get_cam_frame(app);
                 frame =  imgaussfilt(frame,1);
                 MaxVal = max(max(frame));  
                 if MaxVal<220
                      [~, ind] = min(abs(ExpVal-TmpExp));
                      ExpVal = ExpVal(ind:end);
                      TmpExp = ExpVal(round(size(ExpVal,2)/2));
                      app.cam.Timing.Exposure.Set(TmpExp);
                 end
                    
                 if MaxVal>240
                     [~, ind] = min(abs(ExpVal-TmpExp));
                     ExpVal = ExpVal(1:ind);
                     TmpExp = ExpVal(round(size(ExpVal,2)/2));
                     app.cam.Timing.Exposure.Set(TmpExp);
                 end
                    
                 if MaxVal<240 && MaxVal>220
                     flag = 0;
                 end
                 if length(ExpVal)<3
                     flag = 0;
                 end
             end
             app.ExposureEditField.Value = TmpExp;
             ExpValue = TmpExp;
        end
        
        function satur = GetSaturationValue(app)
            if ~isempty(app.cam)
                frame = get_cam_frame(app);
                satur = double(100*sum(frame(:) == 4095)/numel(frame(:)));
                app.saturationField.Value = satur;
            end
        end
        
        function z = GenerateZerPol(app, n, m, r, theta, nflag)
            %ZERNFUN Zernike functions of order N and frequency M on the unit circle.
            %   Z = ZERNFUN(N,M,R,THETA) returns the Zernike functions of order N
            %   and angular frequency M, evaluated at positions (R,THETA) on the
            %   unit circle.  N is a vector of positive integers (including 0), and
            %   M is a vector with the same number of elements as N.  Each element
            %   k of M must be a positive integer, with possible values M(k) = -N(k)
            %   to +N(k) in steps of 2.  R is a vector of numbers between 0 and 1,
            %   and THETA is a vector of angles.  R and THETA must have the same
            %   length.  The output Z is a matrix with one column for every (N,M)
            %   pair, and one row for every (R,THETA) pair.
            %
            %   Z = ZERNFUN(N,M,R,THETA,'norm') returns the normalized Zernike
            %   functions.  The normalization factor sqrt((2-delta(m,0))*(n+1)/pi),
            %   with delta(m,0) the Kronecker delta, is chosen so that the integral
            %   of (r * [Znm(r,theta)]^2) over the unit circle (from r=0 to r=1,
            %   and theta=0 to theta=2*pi) is unity.  For the non-normalized
            %   polynomials, max(Znm(r=1,theta))=1 for all [n,m].
            %
            %   The Zernike functions are an orthogonal basis on the unit circle.
            %   They are used in disciplines such as astronomy, optics, and
            %   optometry to describe functions on a circular domain.
            %
            %   The following table lists the first 15 Zernike functions.
            %
            %       n    m    Zernike function             Normalization
            %       ----------------------------------------------------
            %       0    0    1                              1/sqrt(pi)
            %       1    1    r * cos(theta)                 2/sqrt(pi)
            %       1   -1    r * sin(theta)                 2/sqrt(pi)
            %       2    2    r^2 * cos(2*theta)             sqrt(6/pi)
            %       2    0    (2*r^2 - 1)                    sqrt(3/pi)
            %       2   -2    r^2 * sin(2*theta)             sqrt(6/pi)
            %       3    3    r^3 * cos(3*theta)             sqrt(8/pi)
            %       3    1    (3*r^3 - 2*r) * cos(theta)     sqrt(8/pi)
            %       3   -1    (3*r^3 - 2*r) * sin(theta)     sqrt(8/pi)
            %       3   -3    r^3 * sin(3*theta)             sqrt(8/pi)
            %       4    4    r^4 * cos(4*theta)             sqrt(10/pi)
            %       4    2    (4*r^4 - 3*r^2) * cos(2*theta) sqrt(10/pi)
            %       4    0    6*r^4 - 6*r^2 + 1              sqrt(5/pi)
            %       4   -2    (4*r^4 - 3*r^2) * sin(2*theta) sqrt(10/pi)
            %       4   -4    r^4 * sin(4*theta)             sqrt(10/pi)
            %       ----------------------------------------------------
            %
            %   Example 1:
            %
            %       % Display the Zernike function Z(n=5,m=1)
            %       x = -1:0.01:1;
            %       [X,Y] = meshgrid(x,x);
            %       [theta,r] = cart2pol(X,Y);
            %       idx = r<=1;
            %       z = nan(size(X));
            %       z(idx) = zernfun(5,1,r(idx),theta(idx));
            %       figure
            %       pcolor(x,x,z), shading interp
            %       axis square, colorbar
            %       title('Zernike function Z_5^1(r,\theta)')
            %
            %   Example 2:
            %
            %       % Display the first 10 Zernike functions
            %       x = -1:0.01:1;
            %       [X,Y] = meshgrid(x,x);
            %       [theta,r] = cart2pol(X,Y);
            %       idx = r<=1;
            %       z = nan(size(X));
            %       n = [0  1  1  2  2  2  3  3  3  3];
            %       m = [0 -1  1 -2  0  2 -3 -1  1  3];
            %       Nplot = [4 10 12 16 18 20 22 24 26 28];
            %       y = zernfun(n,m,r(idx),theta(idx));
            %       figure('Units','normalized')
            %       for k = 1:10
            %           z(idx) = y(:,k);
            %           subplot(4,7,Nplot(k))
            %           pcolor(x,x,z), shading interp
            %           set(gca,'XTick',[],'YTick',[])
            %           axis square
            %           title(['Z_{' num2str(n(k)) '}^{' num2str(m(k)) '}'])
            %       end
            %
            % Check and prepare the inputs:
            % -----------------------------
            if ( ~any(size(n)==1) ) || ( ~any(size(m)==1) )
                error('zernfun:NMvectors','N and M must be vectors.')
            end
            if length(n)~=length(m)
                error('zernfun:NMlength','N and M must be the same length.')
            end
            n = n(:);
            m = m(:);
            if any(mod(n-m,2))
                error('zernfun:NMmultiplesof2', ...
                      'All N and M must differ by multiples of 2 (including 0).')
            end
            if any(m>n)
                error('zernfun:MlessthanN', ...
                      'Each M must be less than or equal to its corresponding N.')
            end
            if any( r>1 | r<0 )
                error('zernfun:Rlessthan1','All R must be between 0 and 1.')
            end
            if ( ~any(size(r)==1) ) || ( ~any(size(theta)==1) )
                error('zernfun:RTHvector','R and THETA must be vectors.')
            end
            r = r(:);
            theta = theta(:);
            length_r = length(r);
            if length_r~=length(theta)
                error('zernfun:RTHlength', ...
                      'The number of R- and THETA-values must be equal.')
            end
            % Check normalization:
            % --------------------
            if nargin==5 && ischar(nflag)
                isnorm = strcmpi(nflag,'norm');
                if ~isnorm
                    error('zernfun:normalization','Unrecognized normalization flag.')
                end
            else
                isnorm = false;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Compute the Zernike Polynomials
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Determine the required powers of r:
            % -----------------------------------
            m_abs = abs(m);
            rpowers = [];
            for j = 1:length(n)
                rpowers = [rpowers m_abs(j):2:n(j)];
            end
            rpowers = unique(rpowers);
            % Pre-compute the values of r raised to the required powers,
            % and compile them in a matrix:
            if rpowers(1)==0
                rpowern = arrayfun(@(p)r.^p,rpowers(2:end),'UniformOutput',false);
                rpowern = cat(2,rpowern{:});
                rpowern = [ones(length_r,1) rpowern];
            else
                rpowern = arrayfun(@(p)r.^p,rpowers,'UniformOutput',false);
                rpowern = cat(2,rpowern{:});
            end
            % Compute the values of the polynomials:
            z = zeros(length_r,length(n));
            for j = 1:length(n)
                s = 0:(n(j)-m_abs(j))/2;
                pows = n(j):-2:m_abs(j);
                for k = length(s):-1:1
                    p = (1-2*mod(s(k),2))* ...
                               prod(2:(n(j)-s(k)))/              ...
                               prod(2:s(k))/                     ...
                               prod(2:((n(j)-m_abs(j))/2-s(k)))/ ...
                               prod(2:((n(j)+m_abs(j))/2-s(k)));
                    idx = (pows(k)==rpowers);
                    z(:,j) = z(:,j) + p*rpowern(:,idx);
                end
                
                if isnorm
                    z(:,j) = z(:,j)*sqrt((1+(m(j)~=0))*(n(j)+1)/pi);
                end
            end
            idx_pos = m>0;
            idx_neg = m<0;
            if any(idx_pos)
                z(:,idx_pos) = z(:,idx_pos).*cos(theta*m_abs(idx_pos)');
            end
            if any(idx_neg)
                z(:,idx_neg) = z(:,idx_neg).*sin(theta*m_abs(idx_neg)');
            end
        end
        
        function Write2SLM(app)
            if isa(app.SLM.img,'uint8')
                isEightBit = true;
            else
                isEightBit = false;
            end
            calllib('Blink_C_wrapper', 'Write_image', app.SLM.img, isEightBit);
            pause(0.1);
            
        end
        
        function OptVal = opt_ZerPlo_GA(app, x)
            img = x*app.ZerPol.Dic';
            img = reshape(img, [app.SLM.width, app.SLM.height]);
            img = img - min(min(img));
            app.SLM.img = uint8( mod(img, 256) );
            Write2SLM(app);
            
            for ip=1:25
                fprintf(app.PM100USB, 'READ?');
                data1 = fscanf(app.PM100USB);
                power(ip) = str2num(data1)*1000000;
            end
            OptVal = -mean(power)
            
%             ExpValue = SetAutoExposure(app);
%             img2avg = 9;
%             for ii=1:img2avg
%                 imgtmp(:,:,ii) = double( get_cam_frame(app) );
%             end
%             frame = mean(imgtmp,3);
%             
%             OptVal = ImgCmp(app, abs(app.RefImg).^2, frame)
            
%             results = im2mfd(app, frame);
%             OptVal = (results.D4sigmaX+results.D4sigmaY)/2

        end
        
        function OptVal = opt_Grating_GA(app, x, gs, ps, img4slm, OptVarNum, nps, mps)
            amp = x(1:OptVarNum);
            phase = x(OptVarNum+1:end);
            for ii=1:OptVarNum
                if sum(sum(img4slm{ii}))>0
                    img4slm{ii} = amp(ii)*double(checkerboard(gs,ps/gs/2)>0.5)+phase(ii);
                end
            end
            img4slm = reshape(img4slm, [nps, mps]);
            img4slm = cell2mat(img4slm);
            app.SLM.img = uint8( mod(img4slm, 256) );
            Write2SLM(app);
            
%             for ip=1:16
%                 fprintf(app.PM100USB, 'READ?');
%                 data1 = fscanf(app.PM100USB);
%                 power(ip) = str2num(data1)*1000000;
%             end
%             OptVal = -mean(power)
%             ExpValue = SetAutoExposure(app);
%             img2avg = 9;
%             for ii=1:img2avg
%                 imgtmp(:,:,ii) = double( get_cam_frame(app) );
%             end
%             frame = mean(imgtmp,3);
%             
%             results = im2mfd(app, frame);
%             OptVal = (results.D4sigmaX+results.D4sigmaY)/2

            ExpValue = SetAutoExposure(app);
            frame = double( get_cam_frame(app) );
            OptVal = ImgCmp(app, abs(app.RefImg).^2, frame)
            
            
        end
        
        function [fitresult, gof] = Fit2DGauss(app, x, y, zdata)
            %% Fit: 'untitled fit 1'.
            [xData, yData, zData] = prepareSurfaceData( x, y, zdata );
            
            % Set up fittype and options.
            ft = fittype( 'a*exp(-b*(x-c).^2-b*(y-f).^2)', 'independent', {'x', 'y'}, 'dependent', 'z' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 0 0  0];
            opts.StartPoint = [0.02 0.03 0 0];
            opts.Upper = [0.2 10 64 64];
            
            % Fit model to data.
            [fitresult, gof] = fit( [xData, yData], zData, ft, opts );
        end
        
        function OptVal = opt_Piano_GA(app, x, img)
            ServNum = app.NumberofservosEditField.Value;
             
            for ii = 1:ServNum
                write(app.Piano, [num2str(ii) num2str(fix(x(ii)))], 'char');
                pause(0.05)
            end
            pause(0.1)
            flush(app.Piano)
        
            ExpValue = SetAutoExposure(app);
            frame = double( get_cam_frame(app) );
            frame = frame-2;
            frame(frame<0) = 0;
            img.CData = frame;
            drawnow;
            
            for ip=1:9
                fprintf(app.PM100USB, 'READ?');
                data1 = fscanf(app.PM100USB);
                power(ip) = str2num(data1)*1000000;
            end
            OptVal = -mean(power)
%             
%             results = im2mfd(app, frame);
%             OptVal = (results.D4sigmaX+results.D4sigmaY)/2
            
%             OptVal = ImgCmp(app, abs(app.RefImg).^2, frame)
%             OptVal = -PowerMinMax(app,0.05,1)*1e6
        end
        
        function results = Power_Scan(app)
            PowerVal = eval(app.Range2scan.Value);
            for ii=1:length(PowerVal)
                value = PowerVal(ii);
                if ~isempty(app.ELL14)
                    results = deg2hex(app, 0.5*asind(sqrt(value))*398);
                    cmd = results{1};
                    fprintf(app.ELL14, ['TX 0ma' cmd]);
                    pause(0.05)
                    pos = fgetl(app.ELL14);
                    pause(0.05)
                    app.ELL14Position.Value = hex2dec(pos(4:11))/398;
                    data.power(ii) = value;
                end
                if app.ImageCheckBox.Value
                    if ~isempty(app.cam)
                        ExpValue = SetAutoExposure(app);
                        frame = get_cam_frame(app);
                        data.img(:,:,ii) = frame;
                    end
                end
                if app.SpectrumCheckBox.Value
                    pause(1);
                    if ~isempty(app.OSA)
                        fprintf(app.OSA, 'trac:data:y? tra');
                        t = fscanf(app.OSA) ;
                        r=str2num(t);
                        data.spec(:,ii) = r;
                        data.wl(:,ii) = app.OSA_wl;
                    end
                end
                if app.SLMimageCheckBox.Value
                    if app.SLM.number == 1
                        data.SLMimg(:,:,ii) = app.SLM.img;
                    end
                end
                
                
            end
            % Save data srct
            fname = [app.BasefilenameEditField.Value datestr(now,'mm-dd-yyyy HH-MM-SS-FFF') '.mat'];
            save(fullfile(app.FilepathEditField_3.Value, fname), '-struct', 'data');
        end
        
        function [output, Greg] = dftregistration(app, buf1ft, buf2ft, usfac)
            % Manuel Guizar-Sicairos, Samuel T. Thurman, and James R. Fienup, 
            % "Efficient subpixel image registration algorithms," Opt. Lett. 33, 
            % 156-158 (2008).
            
            % Inputs
            % buf1ft    Fourier transform of reference image, 
            %           DC in (1,1)   [DO NOT FFTSHIFT]
            % buf2ft    Fourier transform of image to register, 
            %           DC in (1,1) [DO NOT FFTSHIFT]
            % usfac     Upsampling factor (integer). Images will be registered to 
            %           within 1/usfac of a pixel. For example usfac = 20 means the
            %           images will be registered within 1/20 of a pixel. (default = 1)
            
            % Outputs
            % output =  [error,diffphase,net_row_shift,net_col_shift]
            % error     Translation invariant normalized RMS error between f and g
            % diffphase     Global phase difference between the two images (should be
            %               zero if images are non-negative).
            % net_row_shift net_col_shift   Pixel shifts between images
            % Greg      (Optional) Fourier transform of registered version of buf2ft,
            %           the global phase difference is compensated for.
            
            % Default usfac to 1
            if exist('usfac', 'var')~=1, usfac=1; end
            
            % Compute error for no pixel shift
            if usfac == 0
                CCmax = sum(sum(buf1ft.*conj(buf2ft))); 
                rfzero = sum(abs(buf1ft(:)).^2);
                rgzero = sum(abs(buf2ft(:)).^2); 
                error = 1.0 - CCmax.*conj(CCmax)/(rgzero*rfzero); 
                error = sqrt(abs(error));
                diffphase=atan2(imag(CCmax),real(CCmax)); 
                output=[error,diffphase];
                    
            % Whole-pixel shift - Compute crosscorrelation by an IFFT and locate the
            % peak
            elseif usfac == 1
                [m,n]=size(buf1ft);
                CC = ifft2(buf1ft.*conj(buf2ft));
                [max1,loc1] = max(CC);
                [max2,loc2] = max(max1);
                rloc=loc1(loc2);
                cloc=loc2;
                CCmax=CC(rloc,cloc); 
                rfzero = sum(abs(buf1ft(:)).^2)/(m*n);
                rgzero = sum(abs(buf2ft(:)).^2)/(m*n); 
                error = 1.0 - CCmax.*conj(CCmax)/(rgzero(1,1)*rfzero(1,1));
                error = sqrt(abs(error));
                diffphase=atan2(imag(CCmax),real(CCmax)); 
                md2 = fix(m/2); 
                nd2 = fix(n/2);
                if rloc > md2
                    row_shift = rloc - m - 1;
                else
                    row_shift = rloc - 1;
                end
            
                if cloc > nd2
                    col_shift = cloc - n - 1;
                else
                    col_shift = cloc - 1;
                end
                output=[error,diffphase,row_shift,col_shift];
                
            % Partial-pixel shift
            else
                
                % First upsample by a factor of 2 to obtain initial estimate
                % Embed Fourier data in a 2x larger array
                [m,n]=size(buf1ft);
                mlarge=m*2;
                nlarge=n*2;
                CC = zeros(mlarge,nlarge);
                CC(m+1-fix(m/2):m+1+fix((m-1)/2),n+1-fix(n/2):n+1+fix((n-1)/2)) = ...
                    fftshift(buf1ft).*conj(fftshift(buf2ft));
                
                % Compute crosscorrelation and locate the peak 
                CC = ifft2(ifftshift(CC)); % Calculate cross-correlation
                [max1,loc1] = max(CC);
                [max2,loc2] = max(max1);
                rloc=loc1(loc2);cloc=loc2;
                CCmax=CC(rloc,cloc);
                
                % Obtain shift in original pixel grid from the position of the
                % crosscorrelation peak 
                [m,n] = size(CC); md2 = fix(m/2); nd2 = fix(n/2);
                if rloc > md2 
                    row_shift = rloc - m - 1;
                else
                    row_shift = rloc - 1;
                end
                if cloc > nd2
                    col_shift = cloc - n - 1;
                else
                    col_shift = cloc - 1;
                end
                row_shift=row_shift/2;
                col_shift=col_shift/2;
            
                % If upsampling > 2, then refine estimate with matrix multiply DFT
                if usfac > 2
                    %%% DFT computation %%%
                    % Initial shift estimate in upsampled grid
                    row_shift = round(row_shift*usfac)/usfac; 
                    col_shift = round(col_shift*usfac)/usfac;     
                    dftshift = fix(ceil(usfac*1.5)/2); %% Center of output array at dftshift+1
                    % Matrix multiply DFT around the current shift estimate
                    CC = conj(dftups(buf2ft.*conj(buf1ft),ceil(usfac*1.5),ceil(usfac*1.5),usfac,...
                        dftshift-row_shift*usfac,dftshift-col_shift*usfac))/(md2*nd2*usfac^2);
                    % Locate maximum and map back to original pixel grid 
                    [max1,loc1] = max(CC);   
                    [max2,loc2] = max(max1); 
                    rloc = loc1(loc2); cloc = loc2;
                    CCmax = CC(rloc,cloc);
                    rg00 = dftups(buf1ft.*conj(buf1ft),1,1,usfac)/(md2*nd2*usfac^2);
                    rf00 = dftups(buf2ft.*conj(buf2ft),1,1,usfac)/(md2*nd2*usfac^2);  
                    rloc = rloc - dftshift - 1;
                    cloc = cloc - dftshift - 1;
                    row_shift = row_shift + rloc/usfac;
                    col_shift = col_shift + cloc/usfac;    
            
                % If upsampling = 2, no additional pixel shift refinement
                else    
                    rg00 = sum(sum( buf1ft.*conj(buf1ft) ))/m/n;
                    rf00 = sum(sum( buf2ft.*conj(buf2ft) ))/m/n;
                end
                error = 1.0 - CCmax.*conj(CCmax)/(rg00*rf00);
                error = sqrt(abs(error));
                diffphase=atan2(imag(CCmax),real(CCmax));
                % If its only one row or column the shift along that dimension has no
                % effect. We set to zero.
                if md2 == 1
                    row_shift = 0;
                end
                if nd2 == 1
                    col_shift = 0;
                end
                output=[error,diffphase,row_shift,col_shift];
            end  
            
            % Compute registered version of buf2ft
            if (nargout > 1)&&(usfac > 0)
                [nr,nc]=size(buf2ft);
                Nr = ifftshift([-fix(nr/2):ceil(nr/2)-1]);
                Nc = ifftshift([-fix(nc/2):ceil(nc/2)-1]);
                [Nc,Nr] = meshgrid(Nc,Nr);
                Greg = buf2ft.*exp(1i*2*pi*(-row_shift*Nr/nr-col_shift*Nc/nc));
                Greg = Greg*exp(1i*diffphase);
            elseif (nargout > 1)&&(usfac == 0)
                Greg = buf2ft*exp(1i*diffphase);
            end
            return
            
            
        end
        
        function out=dftups(in, nor, noc, usfac, roff, coff)
                [nr,nc]=size(in);
                % Set defaults
                if exist('roff', 'var')~=1, roff=0; end
                if exist('coff', 'var')~=1, coff=0; end
                if exist('usfac', 'var')~=1, usfac=1; end
                if exist('noc', 'var')~=1, noc=nc; end
                if exist('nor', 'var')~=1, nor=nr; end
                % Compute kernels and obtain DFT by matrix products
                kernc=exp((-1i*2*pi/(nc*usfac))*( ifftshift([0:nc-1]).' - floor(nc/2) )*( [0:noc-1] - coff ));
                kernr=exp((-1i*2*pi/(nr*usfac))*( [0:nor-1].' - roff )*( ifftshift([0:nr-1]) - floor(nr/2)  ));
                out=kernr*in*kernc;
            return
            
        end
        
        function results = ImgCmp(app, img1, img2)
            N = size(img2,1);
            [x,y] = meshgrid(-N/2:N/2-1, -N/2:N/2-1);
            x = x*app.RefImg_Scal.Value;
            y = y*app.RefImg_Scal.Value;
            [xref,yref] = meshgrid(app.RefImgGrid, app.RefImgGrid);
            imgRef = interp2(xref, yref, img1, x, y, 'cubic', 0);
            imgRef = imgRef/sqrt(sum(sum(abs(imgRef).^2)));
            img2 = img2/sqrt(sum(sum(abs(img2).^2)));
            
            [output, Greg] = dftregistration(app, fft2(imgRef), fft2(img2), 1);
            results = output(1);
        end
        
        function results = PowerMinMax(app,Pmin,Pmax)
            results = deg2hex(app, 0.5*asind(sqrt(Pmin))*398);
            cmd = results{1};
            fprintf(app.ELL14, ['TX 0ma' cmd]);
            pause(0.05)
            
            ExpValue = SetAutoExposure(app);
            frame = double( get_cam_frame(app) );
            frame = frame-2;
            frame(frame<0) = 0;
            framePmin = frame;
            
            results = deg2hex(app, 0.5*asind(sqrt(Pmax))*398);
            cmd = results{1};
            fprintf(app.ELL14, ['TX 0ma' cmd]);
            pause(0.05)
            
            ExpValue = SetAutoExposure(app);
            frame = double( get_cam_frame(app) );
            frame = frame-2;
            frame(frame<0) = 0;
            framePmax = frame;
            
            results = sqrt(sum(sum(abs(framePmax-framePmin).^2)))/...
                (sqrt(sum(sum(abs(framePmax).^2)))*sqrt(sum(sum(abs(framePmin).^2))));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup2.Visible = false;
        end

        % Button pushed function: StartCam
        function StartCamButtonPushed(app, event)
            NET.addAssembly(app.DriverEditField.Value);
            app.cam = uEye.Camera;
            app.cam.Init;
            if app.cam.IsOpened
                app.LampCam.Color = 'green';
                % Set color mode to 12-bit gray
                app.cam.PixelFormat.Set(uEye.Defines.ColorMode.Mono12);
                % correct the timing clock to avoid sampling issues
                app.cam.Timing.PixelClock.Set(197);
                % Disable digital gamma correction
                app.cam.Gamma.Software.Set(100);
                % Disable digital gain correction
                app.cam.Gain.Hardware.Factor.SetMaster(0);
                % Set trigger mode to software (single image acquisition)
                app.cam.Trigger.Set(uEye.Defines.TriggerMode.Software);
                app.cam.Timing.PixelClock.Set(100);
                
                [~, val] = app.cam.Timing.Exposure.GetRange;
                val = struct('Minimum', val.Minimum, 'Maximum', val.Maximum,...
                    'Increment', val.Increment);
                app.ExposureEditField.Limits = [val.Minimum val.Maximum];
                
                [~, app.cam_param.MemId] = app.cam.Memory.Allocate(true);
                [~, app.cam_param.Width, app.cam_param.Height, app.cam_param.Bits,...
                    ~] = app.cam.Memory.Inquire(app.cam_param.MemId);
                [~, inf] = app.cam.Information.GetSensorInfo;
                PixelSize = double(inf.PixelSize)/100;
                [~, inf] = app.cam.Information.GetCameraInfo;
                app.CameraPanel.Title = ['Camera: ' char(inf.BoardType) '   pixel:' num2str(PixelSize) ' [um]'];
%                 app.saturationButton.ButtonPushedFcn;
            end
            
        end

        % Button pushed function: ExitCam
        function ExitCamButtonPushed(app, event)
            if ~isempty(app.cam)
                app.cam.Exit;
                app.LampCam.Color = 'red';
                app.cam = [];
                app.CameraPanel.Title = 'Camera';
            end
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            if ~isempty(app.cam)
                app.cam.Timing.Exposure.Set(value);
            end
        end

        % Button pushed function: PreviewCamera
        function PreviewCameraButtonPushed(app, event)
            if ~isempty(app.cam)
                figH = figure;
                app.PrevFigH = figH;
                img = imagesc(zeros(app.cam_param.Width, app.cam_param.Height)); impixelinfo;
                colormap(app.DropDown.Value);
                set(gca, 'CLim', [0 4095]);
                pbaspect([app.cam_param.Height app.cam_param.Width 1])
                set(gca, 'XTick', [])
                set(gca, 'YTick', [])
                if app.D4sCheckBox.Value
                    hold on
                    elip = plot(1:app.cam_param.Width, 1:app.cam_param.Width,...
                        'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                        'MarkerSize', 3);
                    hold off
                    ylim=get(gca,'ylim');
                    xlim=get(gca,'xlim');
                    textData = {'D4\sigmaX: ', 'D4\sigmaY: '};
                    txtH = text(xlim(1)+2, ylim(1)+75, textData, 'FontSize',14,'Color','white');
                else
                    hold on
                    elip = plot(1:app.cam_param.Width, 1:app.cam_param.Width,...
                        'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                        'MarkerSize', 3);
                    hold off
                    ylim=get(gca,'ylim');
                    xlim=get(gca,'xlim');
                    textData = ' ';
                    txtH = text(xlim(1)+2, ylim(1)+75, textData, 'FontSize',14,'Color','white');
                end


                while ishandle(figH)
                    frame = get_cam_frame(app);
                    img.CData = frame;
                    if app.D4sCheckBox.Value
                        D4sigma = im2mfd(app, frame);
                        elip.XData = D4sigma.xel;
                        elip.YData = D4sigma.yel;
                        txtH.String = {['D4\sigmaX: ' num2str(round(D4sigma.D4sigmaX))...
                            '(' num2str(round(D4sigma.D4sigmaX/1.699)) ')'],...
                            ['D4\sigmaY: ' num2str(round(D4sigma.D4sigmaY))...
                            '(' num2str(round(D4sigma.D4sigmaY/1.699)) ')']};
                    else
                        elip.XData = [];
                        elip.YData = [];
                        txtH.String = [];
                    end
                    drawnow;
                end
                app.PrevFigH = [];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.cam)
                app.cam.Exit;
                app.LampCam.Color = 'red';
                app.cam = [];
            end
            delete(app)
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            value = app.ZoomEditField.Value;
            if ~isempty(app.cam)
                [~, inf] = app.cam.Information.GetSensorInfo;
                MaxWidth = inf.MaxSize.Width;
                MaxHeight = inf.MaxSize.Height;
                zoom = value;
                Left = (MaxWidth - zoom)/2;
                Top = (MaxHeight - zoom)/2;
                app.cam.Size.AOI.Set(Left, Top, zoom, zoom);
                [~, app.cam_param.MemId] = app.cam.Memory.Allocate(true);
                [~, app.cam_param.Width, app.cam_param.Height, app.cam_param.Bits,...
                    ~] = app.cam.Memory.Inquire(app.cam_param.MemId);
                if ishandle(app.PrevFigH)
                    xlim(gca, [1 zoom]);
                    ylim(gca, [1 zoom]);
                end
            end
        end

        % Button pushed function: AutoExposure
        function AutoExposureButtonPushed(app, event)
            if ~isempty(app.cam)
                ExpValue = SetAutoExposure(app);
            end
        end

        % Button pushed function: PreviewCamera_2
        function PreviewCamera_2ButtonPushed(app, event)
            if ~isempty(app.cam)
                frame = get_cam_frame(app);
                fname = ['img_' datestr(now,'mm-dd-yyyy HH-MM-SS-FFF') '.mat'];
                save(fullfile(app.FilepathEditField.Value, fname), 'frame');
            end
        end

        % Button pushed function: FWHMCamera
        function FWHMCameraButtonPushed(app, event)
            if ~isempty(app.cam)
                FWHM = MakeGaussFit2image(app);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if ~isempty(app.PrevFigH)
                colormap(app.PrevFigH, value);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if  isempty(app.ELL14)
                port = app.PortEditField.Value;
                instrreset
                app.ELL14 = serial(port, 'BaudRate', 9600);
                app.ELL14.Timeout = 3;
                fopen(app.ELL14);
                
                if ~strcmp(app.ELL14.Status, 'open')
                    error('Error connecting to serial device')
                else
                    disp("Connected to stage.")
                    app.LampELL14.Color = 'green';
                    fprintf(app.ELL14, 'TX 0in');
                    msg = fgetl(app.ELL14);
                    app.ELL14Panel.Title = ['ELL14: ' msg(1:8)];
                    
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
             if ~isempty(app.ELL14)
                fclose(app.ELL14);
                disp('Disconnected from stage.')
                app.ELL14 = [];
                app.LampELL14.Color = 'red';
                app.ELL14Panel.Title = 'ELL14';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if ~isempty(app.ELL14)
                results = deg2hex(app, value*398);
                cmd = results{1};
                fprintf(app.ELL14, ['TX 0ma' cmd]);
                pause(0.05)
                pos = fgetl(app.ELL14);
                pause(0.05)
                app.ELL14Position.Value = hex2dec(pos(4:11))/398;
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            if ~isempty(app.ELL14)
                fprintf(app.ELL14, 'TX 0ho0');
                pause(0.05)
                msg = fgetl(app.ELL14);
                pause(0.05)
                app.ELL14Position.Value = hex2dec(msg(4:11))/398;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if ~isempty(app.ELL14)
                results = deg2hex(app, 0.5*asind(sqrt(value))*398);
                cmd = results{1};
                fprintf(app.ELL14, ['TX 0ma' cmd]);
                pause(0.05)
                pos = fgetl(app.ELL14);
                pause(0.05)
                app.ELL14Position.Value = hex2dec(pos(4:11))/398;
            end
        end

        % Button pushed function: OpenPM100USB
        function OpenPM100USBPushed(app, event)
            if isempty(app.PM100USB)
                app.PM100USB = instrfind('Type', 'visa-usb', 'RsrcName', 'USB0::0x1313::0x8072::P2009330::0::INSTR', 'Tag', '');

                    % Create the VISA-USB object if it does not exist
                    % otherwise use the object that was found.
                if isempty(app.PM100USB)
                    app.PM100USB = visa('NI', 'USB0::0x1313::0x8072::P2009330::0::INSTR');
                else
                    fclose(app.PM100USB);
                    app.PM100USB = app.PM100USB(1);
                end
                
                % Connect to instrument object, obj1.
                fopen(app.PM100USB);
                if strcmp(app.PM100USB.Status, 'open')
                    fprintf(app.PM100USB, '*IDN?');
                    app.PM100USBPanel.Title = char( fscanf(app.PM100USB) );
                    app.LampPM100USB.Color = 'green';
                end
            end
        end

        % Button pushed function: ClosePM100USB
        function ClosePM100USBButtonPushed(app, event)
            if ~isempty(app.PM100USB)
                fclose(app.PM100USB);
                app.PM100USB = [];
                app.LampPM100USB.Color = 'red';
                app.PM100USBPanel.Title = 'PM100USB';
            end
        end

        % Button pushed function: READPM100USB
        function READPM100USBButtonPushed(app, event)
            if ~isempty(app.PM100USB)
                fprintf(app.PM100USB, 'READ?');
                data1 = fscanf(app.PM100USB);
                app.DATA_PM100USB.Value = str2num(data1)*1000000;
            end
        end

        % Value changed function: SetWAVPM100USB
        function SetWAVPM100USBValueChanged(app, event)
            value = app.SetWAVPM100USB.Value;
            if ~isempty(app.PM100USB)
                fprintf(app.PM100USB, 'SENS:CORR:WAV? MIN' );
                minWAV = str2num(fscanf(app.PM100USB));
                fprintf(app.PM100USB, 'SENS:CORR:WAV? MAX' );
                maxWAV = str2num(fscanf(app.PM100USB));
                if value>minWAV && value<maxWAV
                    fprintf(app.PM100USB, ['SENS:CORR:WAV' num2str(value)]);
                end
            end
        end

        % Button pushed function: Open_OSA
        function Open_OSAButtonPushed(app, event)
            if isempty(app.OSA)
                % Find a VISA-GPIB object.
                app.OSA = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::23::INSTR', 'Tag', '');
                
                % Create the VISA-GPIB object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.OSA)
                    app.OSA = visa('NI', 'GPIB0::23::INSTR');
                else
                    fclose(app.OSA);
                    app.OSA = app.OSA(1);
                end
                set(app.OSA, 'InputBufferSize', 100000);
                set(app.OSA, 'Timeout', 20.0);
                % Connect to instrument object, obj1.
                fopen(app.OSA);
                
                %% Instrument Configuration and Control
                
                % Communicating with instrument object, obj1.
                fprintf(app.OSA, '*IDN?');
                app.OSAPanel.Title = ['OSA   ' char(fscanf(app.OSA))];
                app.LampOSA.Color= 'green';
                
                fprintf(app.OSA, 'sens:wav:star?');
                StartWL = str2num(fscanf(app.OSA));
                
                fprintf(app.OSA, 'sens:wav:stop?');
                StopWL = str2num(fscanf(app.OSA));
                
                fprintf(app.OSA, 'trac:data:y? tra');
                t = fscanf(app.OSA) ;
                r=str2num(t);
                app.OSA_wl = linspace(StartWL,StopWL, size(r,2))*1e9;
            end
        end

        % Button pushed function: Close_OSA
        function Close_OSAButtonPushed(app, event)
            if ~isempty(app.OSA)
                fclose(app.OSA);
                app.OSA = [];
                app.OSA_wl = [];
                app.LampOSA.Color= 'red';
                app.OSAPanel.Title = 'OSA'; 
            end
        end

        % Button pushed function: Get_Spec_OSA
        function Get_Spec_OSAButtonPushed(app, event)
            if ~isempty(app.OSA)
                fprintf(app.OSA, 'trac:data:y? tra');
                t = fscanf(app.OSA) ;
                r=str2num(t);
                figH = figure('pos', [100 100 900 600]);
                plot(app.OSA_wl, r, 'LineWidth',2)
                grid on;
                xlabel('\lambda [nm]','FontSize', 16);
                ylabel('Spectrum [dB]','FontSize', 16);
                xlim([min(app.OSA_wl) max(app.OSA_wl)])
                waitfor(figH);
            end
        end

        % Button pushed function: PreviewCamera_3
        function PreviewCamera_3ButtonPushed(app, event)
            if ~isempty(app.OSA)
                fprintf(app.OSA, 'trac:data:y? tra');
                t = fscanf(app.OSA) ;
                r=str2num(t);
                figH = figure('pos', [100 100 900 600]);
                plot(app.OSA_wl, r, 'LineWidth',2)
                grid on;
                xlabel('\lambda [nm]','FontSize', 16);
                ylabel('Spectrum [dB]','FontSize', 16);
                xlim([min(app.OSA_wl) max(app.OSA_wl)])
     
                spectrum = r;
                wavelengths = app.OSA_wl;
                fname = ['spec_' datestr(now,'mm-dd-yyyy HH-MM-SS-FFF') '.mat'];
                save(fullfile(app.FilepathOSA.Value, fname), 'spectrum', 'wavelengths');
                
                title(['Saved spectrum: ' char( fullfile(app.FilepathOSA.Value, fname) )]);
                waitfor(figH);
            end
        end

        % Button pushed function: Open_SLM
        function Open_SLMButtonPushed(app, event)
            addpath(app.Driver_SLM.Value);
            InitalizeSDK();
            app.SLM.number = calllib('Blink_C_wrapper', 'Get_SLMFound');
            if app.SLM.number == 1
                app.TabGroup2.Visible = true;
                app.SLM.width = calllib('Blink_C_wrapper', 'Get_Width');
                app.SLM.height = calllib('Blink_C_wrapper', 'Get_Height');
                
                % create NULL pointer to the image that will be displayed
                app.SLM.img = libpointer('uint8Ptr', zeros(app.SLM.width*app.SLM.height,1));
                
                [app.SLM.x, app.SLM.y] = meshgrid(-app.SLM.height/2:app.SLM.height/2-1,...
                    -app.SLM.width/2:app.SLM.width/2-1);
                [app.SLM.teta, app.SLM.r] = cart2pol(app.SLM.x, app.SLM.y);
                
                app.Label_SLM.Text = {['Found ' num2str(app.SLM.number) ' SLM'],...
                    ['width: ' num2str(app.SLM.width)],...
                    ['height: ' num2str(app.SLM.height)]};
                app.Lamp_SLM.Color = 'green';
            end
            
            % get SLM temperature
%             SLMTemp = calllib('Blink_C_wrapper', 'Get_SLMTemp');
        end

        % Button pushed function: Close_SLM
        function Close_SLMButtonPushed(app, event)
            if app.SLM.number == 1
                app.SLM.number = [];
                app.Lamp_SLM.Color = 'red';
                app.TabGroup2.Visible = false;
                calllib('Blink_C_wrapper', 'Delete_SDK');
                unloadlibrary('Blink_C_wrapper');
            end
        end

        % Button pushed function: SLM_LoadLUT
        function SLM_LoadLUTButtonPushed(app, event)
            
        end

        % Button pushed function: SLM_Write
        function SLM_WriteButtonPushed(app, event)

            if app.SLM.number == 1
                x = app.SLM.x;
                y = app.SLM.y;
                r = app.SLM.r;
                teta = app.SLM.teta;
                app.SLM.img = eval(app.EditField_SLM_Write.Value);
                app.SLM.img = uint8( mod(app.SLM.img, 256) );
                Write2SLM(app);
            end
        end

        % Button pushed function: SLM_Show
        function SLM_ShowButtonPushed(app, event)
            if app.SLM.number == 1
                x = app.SLM.x;
                y = app.SLM.y;
                r = app.SLM.r;
                teta = app.SLM.teta;
                app.SLM.img = eval(app.EditField_SLM_Write.Value);
                app.SLM.img = uint8( mod(app.SLM.img, 256) );
            end
            figH = figure('pos', [100 100 900 600]);
            imagesc(app.SLM.img');
            colormap(gray(255));
            pbaspect([app.SLM.width app.SLM.height 1]);
            xlabel('Pixel');ylabel('Pixel');
            colorbar;
            waitfor(figH)
        end

        % Button pushed function: Generate_ZerPol
        function Generate_ZerPolButtonPushed(app, event)
            if app.SLM.number == 1
                app.ZerPol.Dic = [];
                
                N = app.EditField_Generate_ZerPol.Value;
                RunningInd = 0;
                for ii=1:N
                    ind = ii-1;
                    M = -ind:2:ind;
                    for ij = 1:length(M)
                        RunningInd = RunningInd +1;
                        Nind(RunningInd) = ind;
                        Mind(RunningInd) = M(ij);
                    end
                end
                app.ZerPol.Nind = Nind;
                app.ZerPol.Mind = Mind;
                rnorm = min([app.SLM.width app.SLM.height])/2-1;
                r = app.SLM.r/rnorm;
                ridx = r<=1;
                teta = app.SLM.teta;
                z = GenerateZerPol(app, Nind, Mind, r(ridx), teta(ridx), 'norm');
                for ii=1:size(z,2)
                    tmp = zeros(app.SLM.width*app.SLM.height,1);
                    tmp(ridx) = z(:,ii);
                    app.ZerPol.Dic(:,ii) = tmp;
                end
                app.Label_ZerPol.Text = ['Generated ' num2str(size(app.ZerPol.Dic,2))...
                    ' Zernike polynomials'];
            end
        end

        % Button pushed function: Exclude_ZerPol
        function Exclude_ZerPolButtonPushed(app, event)
            if ~isempty(app.ZerPol.Dic)
                app.ZerPol.Dic(:, app.EditField_Exclude_ZerPol.Value) = [];
                app.Label_ZerPol.Text = ['Generated ' num2str(size(app.ZerPol.Dic,2))...
                    ' Zernike polynomials'];
            end
        end

        % Button pushed function: Show_ZerPol
        function Show_ZerPolButtonPushed(app, event)
            if ~isempty(app.ZerPol.Dic)
                figH = figure('pos', [100 100 900 600]);
                for ii=1:size(app.ZerPol.Dic,2)
                    tmp = app.ZerPol.Dic(:,ii);
                    tmp = reshape(tmp, [app.SLM.width, app.SLM.height]);
                    subplot(ceil( sqrt(size(app.ZerPol.Dic,2)) ),ceil( sqrt(size(app.ZerPol.Dic,2)) ),ii)
                    imagesc(tmp(1:32:end, 1:32:end)')
                    pbaspect([app.SLM.width app.SLM.height 1]);
                    colormap jet;
                    set(gca, 'XTick', []);
                    set(gca, 'YTick', []);
                    title(['Z_' num2str(app.ZerPol.Nind(ii)) '^{' num2str(app.ZerPol.Mind(ii)) '}(R, \phi)'])
                    drawnow
                end
                waitfor(figH)
            end
        end

        % Button pushed function: Write2SLM_ZerPol
        function Write2SLM_ZerPolButtonPushed(app, event)
            if ~isempty(app.ZerPol.Dic)
                v = eval(app.EditField_ZerPol_vector.Value);
                if size(v,2)==size(app.ZerPol.Dic,2)
                    img = v*app.ZerPol.Dic';
                    img = reshape(img, [app.SLM.width, app.SLM.height]);
                    img = img - min(min(img));
                    app.SLM.img = uint8( mod(img, 256) );
                    Write2SLM(app);
                end
            end
        end

        % Button pushed function: StartCam_2
        function StartCam_2ButtonPushed(app, event)
            if ~isempty(app.ZerPol.Dic)
                lb = eval(app.LBEditField.Value);
                ub = eval(app.UBEditField.Value);
                
%                 intCon = 1:numel(lb);
                intCon = [];
                numberOfVariables = numel(lb);
                FitnessFcn = @(x)opt_ZerPlo_GA(app, x) ;
                options = optimoptions(@ga,'PlotFcn',{@gaplotbestf,@gaplotstopping},...
                     'CrossoverFraction',0.8, 'PopulationSize', 80);
                rng('default');
                [x,fval] = ga(FitnessFcn,numberOfVariables,[],[],[],[],lb,ub,[],intCon,options);
                fval = opt_ZerPlo_GA(app, x) ;
                fprintf(['Best solution x:' num2str(x) '\n']);
                fprintf(['Best value fval:' num2str(fval) '\n']);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            ps = app.PixelSizeEditField.Value;
            gs = app.GratingSizeEditField.Value;
            
            rnorm = min([app.SLM.width app.SLM.height])/2-1;
            r = app.SLM.r/rnorm;
            ridx = double( r<=1 );
            
            [n,m]=size(r);
            aa=1:ps:n;
            bb=1:ps:m;
            [ii,jj]=ndgrid(aa,bb);
            img4slm=arrayfun(@(x,y) ridx(x:x+ps-1,y:y+ps-1),ii,jj,'un',0);
            [nps, mps] = size(img4slm);
            img4slm = img4slm(:);
            
            OptVarNum = numel(img4slm);
            
            lb = zeros(OptVarNum*2,1);
            ub = ones(OptVarNum*2,1)*256;
                
            intCon = 1:numel(lb);
            numberOfVariables = numel(lb);
            FitnessFcn = @(x)opt_Grating_GA(app, x, gs, ps, img4slm, OptVarNum, nps, mps) ;
            options = optimoptions(@ga,'PlotFcn',{@gaplotbestf,@gaplotstopping}); %,'CrossoverFraction',0.8, 'PopulationSize', 80);
            rng('default');
            [x,fval] = ga(FitnessFcn,numberOfVariables,[],[],[],[],lb,ub,[],intCon,options);
            fval = opt_Grating_GA(app, x, gs, ps, img4slm, OptVarNum, nps, mps);
            fprintf(['Best solution x:' num2str(x) '\n']);
            fprintf(['Best value fval:' num2str(fval) '\n']);
        end

        % Button pushed function: SendcommandButton
        function SendcommandButtonPushed(app, event)
            if ~isempty(app.Piano)
                cmd = app.EditFieldPianoCmd.Value;
                write(app.Piano, cmd, 'char');
                pause(0.2)
            end
            
             
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            if isempty(app.Piano)
                port = app.PortEditField_2.Value;
                app.Piano = serialport(port,9600);
                
%                 app.Piano.Timeout = 3;
%                 fopen(app.Piano);
                app.LampPiano.Color = 'green';
            end
        end

        % Button pushed function: CloseButton_2
        function CloseButton_2Pushed(app, event)
            if ~isempty(app.Piano)
                clear app.Piano;
                app.Piano = [];
                disp('Disconnected from Piano.')
                app.LampPiano.Color = 'red';
                
            end
        end

        % Button pushed function: StartCam_3
        function StartCam_3ButtonPushed(app, event)
            if ~isempty(app.Piano)
                figure();
                frame = double( get_cam_frame(app) );
                img = imagesc(frame);
                colormap jet;
                set(gca, 'CLim', [0 255]);
                set(gca, 'XTick', []);
                set(gca, 'YTick', []);
                pbaspect([app.cam_param.Height app.cam_param.Width 1]);
                
                lb = eval(app.LB_Piano.Value);
                ub = eval(app.UB_Piano.Value);
                
                intCon = 1:numel(lb);
                numberOfVariables = numel(lb);
                FitnessFcn = @(x)opt_Piano_GA(app, x, img) ;
                options = optimoptions(@ga,'PlotFcn',{@gaplotbestf,@gaplotstopping},...
                     'CrossoverFraction',0.8, 'PopulationSize', 80);
                rng('default');
                [x,fval] = ga(FitnessFcn,numberOfVariables,[],[],[],[],lb,ub,[],intCon,options);
                fval = opt_Piano_GA(app, x) ;
                fprintf(['Best solution x:' num2str(x) '\n']);
                fprintf(['Best value fval:' num2str(fval) '\n']);
            end
        end

        % Button pushed function: StartCam_4
        function StartCam_4ButtonPushed(app, event)
            tmp = load(app.RefImg_path.Value, app.RefImg_Var.Value);
            app.RefImg = eval(['tmp.' app.RefImg_Var.Value]);
            tmp= load(app.RefImg_path.Value, 'x');
            app.RefImgGrid = eval(['tmp.x']);
            app.RefImgScaling = app.RefImg_Scal.Value;
        end

        % Button pushed function: StartCam_5
        function StartCam_5ButtonPushed(app, event)
            if ~isempty(app.RefImg)
                figH = figure('pos', [100 100 900 600]);
                imagesc(app.RefImgGrid, app.RefImgGrid, abs(app.RefImg).^2)
                colormap jet;
                axis square;
                xlabel('X [\mum]','FontSize', 16);
                ylabel('Y [\mum]','FontSize', 16);
                waitfor(figH);
            end
        end

        % Button pushed function: StartmeasurementButton
        function StartmeasurementButtonPushed(app, event)
            n = app.DropDown_2.Value;
            switch n
                case 'Power Scan'
                    disp('Scanning Power');
                    results = Power_Scan(app);
                case 'Spectral Scan'
                    disp('zero')
                otherwise
                    disp('dont understand?')
            end
        end

        % Button pushed function: SLM_Write_3
        function SLM_Write_3ButtonPushed(app, event)
            fname = app.FileEditField.Value;
            img = imread(fname);
            if size(img,3)>1
                img = rgb2gray(img);
            end
            img = imresize(img, [app.SLM.width app.SLM.height]);
            scale = app.ScaleEditField.Value;
            tform = affine2d([scale 0 0; 0 scale 0; 0 0 1]);
            
            sameAsInput = affineOutputView(size(img),tform,...
                'BoundsStyle','CenterOutput');
            img = imwarp(img, tform, 'OutputView', sameAsInput);
            
            app.SLM.img = uint8( mod(img, 256) );
            Write2SLM(app);
            
        end

        % Button pushed function: SLM_Write_2
        function SLM_Write_2ButtonPushed(app, event)
            r0 = app.r0EditField.Value;
            x = app.SLM.x;
            y = app.SLM.y;
            r = app.SLM.r;
            teta = app.SLM.teta;
            
            imgRef = app.RefImg;
            xRef = app.RefImgGrid;
            NRef = size(imgRef,1);
            zerosind = find(diff(sign(imgRef(NRef/2+1,NRef/2+1:end))));
            xTmp = xRef(NRef/2+1:end);
            xZeros = xTmp(zerosind);
            m = length(xZeros);
            
            img = zeros(size(r,1), size(r,2));
            for ii=1:m
                img((r*r0)>xZeros(ii)) = mod(ii,2);
            end
            img = img*128;
            
            app.SLM.img = uint8( mod(img, 256) );
            Write2SLM(app);
        end

        % Button pushed function: ImgCmpButton
        function ImgCmpButtonPushed(app, event)
            frame = double( get_cam_frame(app) );
            frame = frame-2;
            frame(frame<0)=0;
            N = size(frame,1);
            Nref = size(app.RefImg,1);
            [x,y] = meshgrid(-N/2:N/2-1, -N/2:N/2-1);
            x = x*app.RefImg_Scal.Value;
            y = y*app.RefImg_Scal.Value;
            
            [xref,yref] = meshgrid(app.RefImgGrid, app.RefImgGrid);
            imgRef = interp2(x, y, frame, xref, yref, 'cubic', 0);
            imgRef = imgRef/sqrt(sum(sum(abs(imgRef).^2)));
            
            img2 = abs(app.RefImg).^2;
            img2 = img2/sqrt(sum(sum(abs(img2).^2)));
            
            [output, Greg] = dftregistration(app, fft2(img2), fft2(imgRef), 0.1);
            imgReg = abs( ifft2(Greg) );
            
            figH = figure('Position', [100 100 900 700]);
            subplot(2,2,1)
            imagesc(app.RefImgGrid, app.RefImgGrid, imgReg)
            colormap jet;
            axis square;
            xlabel('X [\mum]');ylabel('Y [\mum]');
            
            subplot(2,2,2)
            imagesc(app.RefImgGrid, app.RefImgGrid, img2)
            colormap jet;
            axis square;
            xlabel('X [\mum]');ylabel('Y [\mum]');
            
            subplot(2,2,3)
            plot(app.RefImgGrid, imgReg(Nref/2+1,:), 'LineWidth',2)
            hold on
            plot(app.RefImgGrid, img2(Nref/2+1,:), 'LineWidth',2, 'LineStyle',':')
            hold off
            xlim([min(app.RefImgGrid) max(app.RefImgGrid)])
            xlabel('X [\mum]');ylabel('Intensity [norm]');
            
            subplot(2,2,4)
            plot(app.RefImgGrid, imgReg(:,Nref/2+1), 'LineWidth',2)
            hold on
            plot(app.RefImgGrid, img2(:,Nref/2+1), 'LineWidth',2, 'LineStyle',':')
            hold off
            xlim([min(app.RefImgGrid) max(app.RefImgGrid)])
            xlabel('Y [\mum]');ylabel('Intensity [norm]');
            waitfor(figH);
        end

        % Button pushed function: RunButton_LP_opt
        function RunButton_LP_optPushed(app, event)
            ps = app.PixelSize_LP_opt.Value;
            gs = app.GratingSize_LP_opt.Value;
            
            rnorm = min([app.SLM.width app.SLM.height])/2-1;
            r = app.SLM.r/rnorm;
            ridx = double( r<=0.5 );
            
            [n,m]=size(r);
            aa=1:ps:n;
            bb=1:ps:m;
            [ii,jj]=ndgrid(aa,bb);
            img4slm=arrayfun(@(x,y) ridx(x:x+ps-1,y:y+ps-1),ii,jj,'un',0);
            [nps, mps] = size(img4slm);
            img4slm = img4slm(:);
            
            OptVarNum = numel(img4slm);
            
            lb = zeros(OptVarNum*2,1);
            ub = ones(OptVarNum*2,1)*255;
                
            intCon = 1:numel(lb);
            numberOfVariables = numel(lb);
            FitnessFcn = @(x)opt_Grating_GA(app, x, gs, ps, img4slm, OptVarNum, nps, mps) ;
            options = optimoptions(@ga,'PlotFcn',{@gaplotbestf,@gaplotstopping}); %,'CrossoverFraction',0.8, 'PopulationSize', 80);
            rng('default');
            [x,fval] = ga(FitnessFcn,numberOfVariables,[],[],[],[],lb,ub,[],intCon,options);
            fval = opt_Grating_GA(app, x, gs, ps, img4slm, OptVarNum, nps, mps);
            fprintf(['Best solution x:' num2str(x) '\n']);
            fprintf(['Best value fval:' num2str(fval) '\n']);
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            if ~isempty(app.cam)
                frame = get_cam_frame(app);
                figure();
                hist = histogram(frame(:));
                title(['max value = ',num2str(max(frame(:)))]);
            end
        end

        % Button pushed function: saturationButton
        function saturationButtonPushed(app, event)
            if ~isempty(app.cam)
                satur = GetSaturationValue(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraPanel = uipanel(app.CameraTab);
            app.CameraPanel.Title = 'Camera';
            app.StartCam = uibutton(app.CameraPanel, 'push');
            app.StartCam.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCam.Text = 'Open';
            app.ExitCam = uibutton(app.CameraPanel, 'push');
            app.ExitCam.ButtonPushedFcn = createCallbackFcn(app, @ExitCamButtonPushed, true);
            app.ExitCam.Text = 'Close';
            app.DriverEditFieldLabel = uilabel(app.CameraPanel);
            app.DriverEditFieldLabel.Text = 'Driver';
            app.DriverEditField = uieditfield(app.CameraPanel, 'text');
            app.DriverEditField.Value = 'C:\Program Files\IDS\uEye\Develop\DotNet\uEyeDotNet.dll';
            app.ExposureEditFieldLabel = uilabel(app.CameraPanel);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ExposureEditField.Limits = [0 Inf];
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.AutoExposure = uibutton(app.CameraPanel, 'push');
            app.AutoExposure.ButtonPushedFcn = createCallbackFcn(app, @AutoExposureButtonPushed, true);
            app.AutoExposure.Text = 'Auto';
            app.PreviewCamera = uibutton(app.CameraPanel, 'push');
            app.PreviewCamera.ButtonPushedFcn = createCallbackFcn(app, @PreviewCameraButtonPushed, true);
            app.PreviewCamera.Text = 'Preview';
            app.PreviewCamera_2 = uibutton(app.CameraPanel, 'push');
            app.PreviewCamera_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewCamera_2ButtonPushed, true);
            app.PreviewCamera_2.Text = 'Save';
            app.ZoomEditFieldLabel = uilabel(app.CameraPanel);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ZoomEditField.Limits = [0 3004];
            app.ZoomEditField.ValueDisplayFormat = '%.0f';
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.ZoomEditField.Value = 3004;
            app.D4sCheckBox = uicheckbox(app.CameraPanel);
            app.D4sCheckBox.Text = 'D4s';
            app.FilepathEditFieldLabel = uilabel(app.CameraPanel);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.CameraPanel, 'text');
            app.FilepathEditField.Value = 'C:\Users\arielv\Desktop\Master\images';
            app.FWHMCamera = uibutton(app.CameraPanel, 'push');
            app.FWHMCamera.ButtonPushedFcn = createCallbackFcn(app, @FWHMCameraButtonPushed, true);
            app.FWHMCamera.Text = 'FWHM';
            app.DropDown = uidropdown(app.CameraPanel);
            app.DropDown.Items = {'jet', 'gray', 'hsv', 'hot', 'parula'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'jet';
            app.HistogramButton = uibutton(app.CameraPanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.saturationField = uieditfield(app.CameraPanel, 'numeric');
            app.saturationButton = uibutton(app.CameraPanel, 'push');
            app.saturationButton.ButtonPushedFcn = createCallbackFcn(app, @saturationButtonPushed, true);
            app.saturationButton.Text = '% saturation';
            app.LoadreferenceimagePanel = uipanel(app.CameraTab);
            app.LoadreferenceimagePanel.Title = 'Load reference image';
            app.StartCam_4 = uibutton(app.LoadreferenceimagePanel, 'push');
            app.StartCam_4.ButtonPushedFcn = createCallbackFcn(app, @StartCam_4ButtonPushed, true);
            app.StartCam_4.Text = 'Load';
            app.FilepathEditField_4Label = uilabel(app.LoadreferenceimagePanel);
            app.FilepathEditField_4Label.Text = 'File path';
            app.RefImg_path = uieditfield(app.LoadreferenceimagePanel, 'text');
            app.RefImg_path.Value = 'C:\Users\WiseOffice2\Desktop\Pavel\SLM setup\Fibers\XLMA_YTF_100_400_480_cilindric\LP_000-009_wavelength1030.mat';
            app.ScalingEditFieldLabel = uilabel(app.LoadreferenceimagePanel);
            app.ScalingEditFieldLabel.Text = 'Scaling';
            app.RefImg_Scal = uieditfield(app.LoadreferenceimagePanel, 'numeric');
            app.RefImg_Scal.Value = 0.24;
            app.VariableEditFieldLabel = uilabel(app.LoadreferenceimagePanel);
            app.VariableEditFieldLabel.Text = 'Variable';
            app.RefImg_Var = uieditfield(app.LoadreferenceimagePanel, 'text');
            app.RefImg_Var.Value = 'phi';
            app.StartCam_5 = uibutton(app.LoadreferenceimagePanel, 'push');
            app.StartCam_5.ButtonPushedFcn = createCallbackFcn(app, @StartCam_5ButtonPushed, true);
            app.StartCam_5.Text = 'Show';
            app.ImgCmpButton = uibutton(app.LoadreferenceimagePanel, 'push');
            app.ImgCmpButton.ButtonPushedFcn = createCallbackFcn(app, @ImgCmpButtonPushed, true);
            app.ImgCmpButton.Text = 'ImgCmp';
            app.SLMTab = uitab(app.TabGroup);
            app.SLMTab.Title = 'SLM';
            app.Open_SLM = uibutton(app.SLMTab, 'push');
            app.Open_SLM.ButtonPushedFcn = createCallbackFcn(app, @Open_SLMButtonPushed, true);
            app.Open_SLM.Text = 'Open';
            app.Close_SLM = uibutton(app.SLMTab, 'push');
            app.Close_SLM.ButtonPushedFcn = createCallbackFcn(app, @Close_SLMButtonPushed, true);
            app.Close_SLM.Text = 'Close';
            app.DriverEditField_2Label = uilabel(app.SLMTab);
            app.DriverEditField_2Label.Text = 'Driver';
            app.Driver_SLM = uieditfield(app.SLMTab, 'text');
            app.Driver_SLM.Value = 'C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\SDK';
            app.Label_SLM = uilabel(app.SLMTab);
            app.Label_SLM.Text = '';
            app.LUTEditFieldLabel = uilabel(app.SLMTab);
            app.LUTEditFieldLabel.Text = 'LUT';
            app.LUTEditField = uieditfield(app.SLMTab, 'text');
            app.LUTEditField.Value = 'C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\SDK\slm4752.bmp';
            app.SLM_LoadLUT = uibutton(app.SLMTab, 'push');
            app.SLM_LoadLUT.ButtonPushedFcn = createCallbackFcn(app, @SLM_LoadLUTButtonPushed, true);
            app.SLM_LoadLUT.Text = 'Load LUT';
            app.ClosingSLMwillkillMatLabLabel = uilabel(app.SLMTab);
            app.ClosingSLMwillkillMatLabLabel.Text = 'Closing SLM will kill MatLab!';
            app.StageTab = uitab(app.TabGroup);
            app.StageTab.Title = 'Stage';
            app.ELL14Panel = uipanel(app.StageTab);
            app.ELL14Panel.Title = 'ELL14';
            app.PortEditFieldLabel = uilabel(app.ELL14Panel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ELL14Panel, 'text');
            app.PortEditField.Value = 'COM5';
            app.OpenButton = uibutton(app.ELL14Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.ELL14Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.HomeButton = uibutton(app.ELL14Panel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ELL14Position = uieditfield(app.ELL14Panel, 'numeric');
            app.ELL14Position.Editable = 'off';
            app.EditField = uieditfield(app.ELL14Panel, 'numeric');
            app.EditField.Limits = [0 359.9];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ELL14Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Go2DegreeLabel = uilabel(app.ELL14Panel);
            app.Go2DegreeLabel.Text = 'Go2Degree';
            app.Go2PowerLabel = uilabel(app.ELL14Panel);
            app.Go2PowerLabel.Text = 'Go2Power';
            app.PowMetTab = uitab(app.TabGroup);
            app.PowMetTab.Title = 'PowMet';
            app.PM100USBPanel = uipanel(app.PowMetTab);
            app.PM100USBPanel.Title = 'PM100USB';
            app.OpenPM100USB = uibutton(app.PM100USBPanel, 'push');
            app.OpenPM100USB.ButtonPushedFcn = createCallbackFcn(app, @OpenPM100USBPushed, true);
            app.OpenPM100USB.Text = 'Open';
            app.ClosePM100USB = uibutton(app.PM100USBPanel, 'push');
            app.ClosePM100USB.ButtonPushedFcn = createCallbackFcn(app, @ClosePM100USBButtonPushed, true);
            app.ClosePM100USB.Text = 'Close';
            app.SetWAVPM100USB = uieditfield(app.PM100USBPanel, 'numeric');
            app.SetWAVPM100USB.Limits = [0 Inf];
            app.SetWAVPM100USB.ValueChangedFcn = createCallbackFcn(app, @SetWAVPM100USBValueChanged, true);
            app.SetWAVPM100USB.Value = 400;
            app.SETWAVELabel = uilabel(app.PM100USBPanel);
            app.SETWAVELabel.Text = 'SET WAVE';
            app.READPM100USB = uibutton(app.PM100USBPanel, 'push');
            app.READPM100USB.ButtonPushedFcn = createCallbackFcn(app, @READPM100USBButtonPushed, true);
            app.READPM100USB.Text = 'READ';
            app.DATA_PM100USB = uieditfield(app.PM100USBPanel, 'numeric');
            app.DATA_PM100USB.Limits = [0 Inf];
            app.OSATab = uitab(app.TabGroup);
            app.OSATab.Title = 'OSA';
            app.OSAPanel = uipanel(app.OSATab);
            app.OSAPanel.Title = 'OSA';
            app.Open_OSA = uibutton(app.OSAPanel, 'push');
            app.Open_OSA.ButtonPushedFcn = createCallbackFcn(app, @Open_OSAButtonPushed, true);
            app.Open_OSA.Text = 'Open';
            app.Close_OSA = uibutton(app.OSAPanel, 'push');
            app.Close_OSA.ButtonPushedFcn = createCallbackFcn(app, @Close_OSAButtonPushed, true);
            app.Close_OSA.Text = 'Close';
            app.Get_Spec_OSA = uibutton(app.OSAPanel, 'push');
            app.Get_Spec_OSA.ButtonPushedFcn = createCallbackFcn(app, @Get_Spec_OSAButtonPushed, true);
            app.Get_Spec_OSA.Text = 'Get Spectrum';
            app.PreviewCamera_3 = uibutton(app.OSAPanel, 'push');
            app.PreviewCamera_3.ButtonPushedFcn = createCallbackFcn(app, @PreviewCamera_3ButtonPushed, true);
            app.PreviewCamera_3.Text = 'Save';
            app.FilepathEditField_2Label = uilabel(app.OSAPanel);
            app.FilepathEditField_2Label.Text = 'File path';
            app.FilepathOSA = uieditfield(app.OSAPanel, 'text');
            app.FilepathOSA.Value = 'C:\Users\WiseOffice2\Desktop\Pavel\SLM setup';
            app.PianoTab = uitab(app.TabGroup);
            app.PianoTab.Title = 'Piano';
            app.NumberofservosEditFieldLabel = uilabel(app.PianoTab);
            app.NumberofservosEditFieldLabel.Text = 'Number of servos';
            app.NumberofservosEditField = uieditfield(app.PianoTab, 'numeric');
            app.NumberofservosEditField.Limits = [1 128];
            app.NumberofservosEditField.ValueDisplayFormat = '%.0f';
            app.NumberofservosEditField.Value = 8;
            app.SendcommandButton = uibutton(app.PianoTab, 'push');
            app.SendcommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendcommandButtonPushed, true);
            app.SendcommandButton.Text = 'Send command';
            app.EditFieldPianoCmd = uieditfield(app.PianoTab, 'text');
            app.EditFieldPianoCmd.Value = '1500';
            app.Firstdigitservonumber24positionLabel = uilabel(app.PianoTab);
            app.Firstdigitservonumber24positionLabel.Text = 'First digit servo number, 2-4 position.';
            app.GAoptimizationPanel = uipanel(app.PianoTab);
            app.GAoptimizationPanel.Title = 'GA optimization';
            app.StartCam_3 = uibutton(app.GAoptimizationPanel, 'push');
            app.StartCam_3.ButtonPushedFcn = createCallbackFcn(app, @StartCam_3ButtonPushed, true);
            app.StartCam_3.Text = 'Run';
            app.LBEditField_2Label = uilabel(app.GAoptimizationPanel);
            app.LBEditField_2Label.Text = 'LB';
            app.LB_Piano = uieditfield(app.GAoptimizationPanel, 'text');
            app.LB_Piano.Value = '450*ones(1,8)';
            app.UBEditField_2Label = uilabel(app.GAoptimizationPanel);
            app.UBEditField_2Label.Text = 'UB';
            app.UB_Piano = uieditfield(app.GAoptimizationPanel, 'text');
            app.UB_Piano.Value = '550*ones(1,8)';
            app.OpenButton_2 = uibutton(app.PianoTab, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.CloseButton_2 = uibutton(app.PianoTab, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_2Pushed, true);
            app.CloseButton_2.Text = 'Close';
            app.PortEditField_2Label = uilabel(app.PianoTab);
            app.PortEditField_2Label.Text = 'Port';
            app.PortEditField_2 = uieditfield(app.PianoTab, 'text');
            app.PortEditField_2.Value = 'COM6';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.DropDown_2 = uidropdown(app.MeasurementTab);
            app.DropDown_2.Items = {'Power Scan', 'Spectral Scan'};
            app.DropDown_2.Value = 'Power Scan';
            app.SavedataPanel = uipanel(app.MeasurementTab);
            app.SavedataPanel.Title = 'Save data';
            app.ImageCheckBox = uicheckbox(app.SavedataPanel);
            app.ImageCheckBox.Text = 'Image';
            app.ImageCheckBox.Value = true;
            app.SpectrumCheckBox = uicheckbox(app.SavedataPanel);
            app.SpectrumCheckBox.Text = 'Spectrum';
            app.SpectrumCheckBox.Value = true;
            app.SLMimageCheckBox = uicheckbox(app.SavedataPanel);
            app.SLMimageCheckBox.Text = 'SLM image';
            app.SLMimageCheckBox.Value = true;
            app.PowerCheckBox = uicheckbox(app.SavedataPanel);
            app.PowerCheckBox.Text = 'Power';
            app.PolarizationCheckBox = uicheckbox(app.SavedataPanel);
            app.PolarizationCheckBox.Text = 'Polarization';
            app.FilepathEditField_2Label_2 = uilabel(app.MeasurementTab);
            app.FilepathEditField_2Label_2.Text = 'File path';
            app.FilepathEditField_3 = uieditfield(app.MeasurementTab, 'text');
            app.FilepathEditField_3.Value = 'C:\Users\WiseOffice2\Desktop\Pavel\SLM setup';
            app.BasefilenameEditFieldLabel = uilabel(app.MeasurementTab);
            app.BasefilenameEditFieldLabel.Text = 'Base file name';
            app.BasefilenameEditField = uieditfield(app.MeasurementTab, 'text');
            app.BasefilenameEditField.Value = 'data_';
            app.StartmeasurementButton = uibutton(app.MeasurementTab, 'push');
            app.StartmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartmeasurementButtonPushed, true);
            app.StartmeasurementButton.Text = 'Start measurement';
            app.RangetoscanEditFieldLabel = uilabel(app.MeasurementTab);
            app.RangetoscanEditFieldLabel.Text = 'Range to scan';
            app.Range2scan = uieditfield(app.MeasurementTab, 'text');
            app.Range2scan.Value = '[0:0.1:1]';
            app.LampCam = uilamp(app.UIFigure);
            app.LampELL14 = uilamp(app.UIFigure);
            app.LampPM100USB = uilamp(app.UIFigure);
            app.LampOSA = uilamp(app.UIFigure);
            app.Lamp_SLM = uilamp(app.UIFigure);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Write2SLMTab = uitab(app.TabGroup2);
            app.Write2SLMTab.Title = 'Write2SLM';
            app.EditField_SLM_Write = uieditfield(app.Write2SLMTab, 'text');
            app.EditField_SLM_Write.Value = '1*(x.^2+y.^2)';
            app.SLM_Write = uibutton(app.Write2SLMTab, 'push');
            app.SLM_Write.ButtonPushedFcn = createCallbackFcn(app, @SLM_WriteButtonPushed, true);
            app.SLM_Write.Text = 'Write';
            app.SLM_Show = uibutton(app.Write2SLMTab, 'push');
            app.SLM_Show.ButtonPushedFcn = createCallbackFcn(app, @SLM_ShowButtonPushed, true);
            app.SLM_Show.Text = 'Show';
            app.SLM_Write_3 = uibutton(app.Write2SLMTab, 'push');
            app.SLM_Write_3.ButtonPushedFcn = createCallbackFcn(app, @SLM_Write_3ButtonPushed, true);
            app.SLM_Write_3.Text = 'Write';
            app.FileEditFieldLabel = uilabel(app.Write2SLMTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.Write2SLMTab, 'text');
            app.ScaleEditFieldLabel = uilabel(app.Write2SLMTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.Write2SLMTab, 'numeric');
            app.ScaleEditField.Value = 1;
            app.LP0mTab = uitab(app.TabGroup2);
            app.LP0mTab.Title = 'LP0m';
            app.SLM_Write_2 = uibutton(app.LP0mTab, 'push');
            app.SLM_Write_2.ButtonPushedFcn = createCallbackFcn(app, @SLM_Write_2ButtonPushed, true);
            app.SLM_Write_2.Text = 'Write';
            app.mEditFieldLabel = uilabel(app.LP0mTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.LP0mTab, 'numeric');
            app.mEditField.Value = 3;
            app.r0EditFieldLabel = uilabel(app.LP0mTab);
            app.r0EditFieldLabel.Text = 'r0';
            app.r0EditField = uieditfield(app.LP0mTab, 'numeric');
            app.r0EditField.Value = 0.175;
            app.OptimizerPanel = uipanel(app.LP0mTab);
            app.OptimizerPanel.Title = 'Optimizer';
            app.RunButton_LP_opt = uibutton(app.OptimizerPanel, 'push');
            app.RunButton_LP_opt.ButtonPushedFcn = createCallbackFcn(app, @RunButton_LP_optPushed, true);
            app.RunButton_LP_opt.Text = 'Run';
            app.PixelSizeEditFieldLabel_2 = uilabel(app.OptimizerPanel);
            app.PixelSizeEditFieldLabel_2.Text = 'Pixel Size';
            app.PixelSize_LP_opt = uieditfield(app.OptimizerPanel, 'numeric');
            app.PixelSize_LP_opt.Value = 16;
            app.GratingSizeEditField_2Label = uilabel(app.OptimizerPanel);
            app.GratingSizeEditField_2Label.Text = 'Grating Size';
            app.GratingSize_LP_opt = uieditfield(app.OptimizerPanel, 'numeric');
            app.GratingSize_LP_opt.Value = 4;
            app.ZernikePolTab = uitab(app.TabGroup2);
            app.ZernikePolTab.Title = 'Zernike Pol';
            app.Generate_ZerPol = uibutton(app.ZernikePolTab, 'push');
            app.Generate_ZerPol.ButtonPushedFcn = createCallbackFcn(app, @Generate_ZerPolButtonPushed, true);
            app.Generate_ZerPol.Text = 'Generate';
            app.Exclude_ZerPol = uibutton(app.ZernikePolTab, 'push');
            app.Exclude_ZerPol.ButtonPushedFcn = createCallbackFcn(app, @Exclude_ZerPolButtonPushed, true);
            app.Exclude_ZerPol.Text = 'Exclude';
            app.Show_ZerPol = uibutton(app.ZernikePolTab, 'push');
            app.Show_ZerPol.ButtonPushedFcn = createCallbackFcn(app, @Show_ZerPolButtonPushed, true);
            app.Show_ZerPol.Text = 'Show';
            app.Write2SLM_ZerPol = uibutton(app.ZernikePolTab, 'push');
            app.Write2SLM_ZerPol.ButtonPushedFcn = createCallbackFcn(app, @Write2SLM_ZerPolButtonPushed, true);
            app.Write2SLM_ZerPol.Text = 'Write2SLM';
            app.EditField_Generate_ZerPol = uieditfield(app.ZernikePolTab, 'numeric');
            app.EditField_Generate_ZerPol.Limits = [1 Inf];
            app.EditField_Generate_ZerPol.Value = 1;
            app.EditField_Exclude_ZerPol = uieditfield(app.ZernikePolTab, 'numeric');
            app.EditField_Exclude_ZerPol.Limits = [1 Inf];
            app.EditField_Exclude_ZerPol.Value = 1;
            app.Label_ZerPol = uilabel(app.ZernikePolTab);
            app.Label_ZerPol.Text = ' ';
            app.EditField_ZerPol_vector = uieditfield(app.ZernikePolTab, 'text');
            app.GATab = uitab(app.TabGroup2);
            app.GATab.Title = 'GA';
            app.ZernikePolPanel = uipanel(app.GATab);
            app.ZernikePolPanel.Title = 'Zernike Pol';
            app.StartCam_2 = uibutton(app.ZernikePolPanel, 'push');
            app.StartCam_2.ButtonPushedFcn = createCallbackFcn(app, @StartCam_2ButtonPushed, true);
            app.StartCam_2.Text = 'Run';
            app.LBEditFieldLabel = uilabel(app.ZernikePolPanel);
            app.LBEditFieldLabel.Text = 'LB';
            app.LBEditField = uieditfield(app.ZernikePolPanel, 'text');
            app.LBEditField.Value = '-100*ones(1,9)';
            app.UBEditFieldLabel = uilabel(app.ZernikePolPanel);
            app.UBEditFieldLabel.Text = 'UB';
            app.UBEditField = uieditfield(app.ZernikePolPanel, 'text');
            app.UBEditField.Value = '100*ones(1,9)';
            app.MakropixelgratingPanel = uipanel(app.GATab);
            app.MakropixelgratingPanel.Title = 'Makro pixel grating';
            app.RunButton = uibutton(app.MakropixelgratingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PixelSizeEditFieldLabel = uilabel(app.MakropixelgratingPanel);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.MakropixelgratingPanel, 'numeric');
            app.PixelSizeEditField.Value = 16;
            app.GratingSizeEditFieldLabel = uilabel(app.MakropixelgratingPanel);
            app.GratingSizeEditFieldLabel.Text = 'Grating Size';
            app.GratingSizeEditField = uieditfield(app.MakropixelgratingPanel, 'numeric');
            app.GratingSizeEditField.Value = 4;
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.LampPiano = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_setup_app_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end